classdef OxiCheck < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ResumenTab                 matlab.ui.container.Tab
        SimbologiaRangosPanel      matlab.ui.container.Panel
        LeyendoLamp                matlab.ui.control.Lamp
        LeyendoLampLabel           matlab.ui.control.Label
        FueraderangoLamp_2         matlab.ui.control.Lamp
        FueraderangoLamp_2Label    matlab.ui.control.Label
        EstableLamp                matlab.ui.control.Lamp
        EstableLampLabel           matlab.ui.control.Label
        ResultadoPanel             matlab.ui.container.Panel
        FueraderangoLamp           matlab.ui.control.Lamp
        FueraderangoLampLabel      matlab.ui.control.Label
        OxigenacionEditField       matlab.ui.control.NumericEditField
        OxigenacionEditFieldLabel  matlab.ui.control.Label
        ProgresogeneralPanel       matlab.ui.container.Panel
        Gauge_2                    matlab.ui.control.Gauge
        SimbologiaEstadoPanel      matlab.ui.container.Panel
        DatosmandadosLamp          matlab.ui.control.Lamp
        DatosmandadosLampLabel     matlab.ui.control.Label
        ProcesandodatosLamp        matlab.ui.control.Lamp
        ProcesandodatosLampLabel   matlab.ui.control.Label
        ProcesandoLamp             matlab.ui.control.Lamp
        ProcesandoLampLabel        matlab.ui.control.Label
        ApagadoLamp                matlab.ui.control.Lamp
        ApagadoLampLabel           matlab.ui.control.Label
        GraficasTab                matlab.ui.container.Tab
        DatosPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        Tablas                     matlab.ui.container.Tab
        Panel                      matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
        ConexionPanel              matlab.ui.container.Panel
        EstadoLamp                 matlab.ui.control.Lamp
        EstadoLampLabel            matlab.ui.control.Label
        LeerButton                 matlab.ui.control.Button
        DesconectarButton          matlab.ui.control.Button
        DropDown_2                 matlab.ui.control.DropDown
        ConectadoLamp              matlab.ui.control.Lamp
        ConectadoLampLabel         matlab.ui.control.Label
        ConectarButton             matlab.ui.control.Button
        DropDown                   matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        S % Description
    end
    
    methods (Access = private)
        
        function serialWrite(app,Mensaje,SerialPW)
            writeline(SerialPW,Mensaje);% Write data to serial
        end
        
        function Read = serialRead(app,SerialPR)
            Read = readline(SerialPR);% Read data of serial
        end
        
        function newData = cleanData(app,oldData)
            len = length(oldData);%Get length of data
            newData = [];
            %Delete all invalid data
            for i = 1:len
                if oldData(i)~=-999 && oldData(i)>=50
                    disp("Valid data");
                    newData = [newData oldData(i)];
                else
                    disp("Invalid data");
                end
            end
            
            %Show error if no valid data
            if length(newData)==0
                error("No data")
            end
        end

        function [meanVal, upVal, downVal] = resultData(app,data)
            meanVal = mean(data);% Get mean of data
            upVal = mean(maxk(data,3));% Get mean of 3 max values
            downVal = mean(mink(data,3));% Get mean of 3 min values
        end

        function response = sendData(app,url,ave,up,down)
            dataJSON = struct("Alta", up, "Baja", down, "Promedio", ave); % Codify data to send
            options = weboptions('MediaType', 'application/json'); % Config options
            response = webwrite(url, dataJSON, options); % HTTP POST
            disp(response); % Display response
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            %Get all serial ports to dropdown
            app.DropDown.Items = serialportlist;
            if ~isequal(app.DropDown.Items,[])
                app.ConectarButton.Enable = true;
            end
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            %Connect serial port
            value = app.DropDown.Value;
            valueFrec = str2double(app.DropDown_2.Value);
            app.S = serialport(value,valueFrec);
            app.DesconectarButton.Enable = true;
            app.ConectadoLamp.Color = [0 1 0];
            app.ConectarButton.Enable = false;
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            %Clear serial connection
            clear app.S;
            app.ConectadoLamp.Color = [1 0 0];
            app.ConectarButton.Enable = true;
            app.DesconectarButton.Enable = false;
            app.DropDown.Items = [""];
        end

        % Button pushed function: LeerButton
        function LeerButtonPushed(app, event)
            %Set lamps off
            app.EstadoLamp.Color = [0,1,1];
            app.FueraderangoLamp.Color = [0,1,0];
            app.OxigenacionEditField.Value = 0;
            app.Gauge_2.Value = 0;
            
            %Set time
            time = 129;
            app.serialWrite("R",app.S); %start reading data

            %Wait time
            for i = 1:time
                app.Gauge_2.Value = i/time*100;
                disp(string(i/time*100)+"%");
                pause(1);
            end
            
            %Format data
            Lec = strtrim(app.serialRead(app.S));
            Lec = replace(Lec,"I","[");
            Lec = replace(Lec,"F","]");
            Read = eval(Lec);
            disp("End");
            app.EstadoLamp.Color = [0,0,1];

            %Clean data
            cleanedData = app.cleanData(Read);
            
            %Data results
            [meanVal, upVal, downVal] = app.resultData(cleanedData);
            
            %Send data
            url = "http://35.238.147.212:3000/api";
            app.sendData(url, meanVal, upVal, downVal);

            %Set lamps finish
            app.EstadoLamp.Color = [0,1,0];

            %Plot mean
            app.OxigenacionEditField.Value = meanVal;
            if meanVal>95
                app.FueraderangoLamp.Color = [0,1,0];
            else
                app.FueraderangoLamp.Color = [1,0,0];
            end
            
            %Plot data and table
            nData = 1:length(cleanedData);
            plot(app.UIAxes,nData,cleanedData);
            app.UITable.Data = table(nData',cleanedData');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConexionPanel = uipanel(app.UIFigure);
            app.ConexionPanel.Title = 'Conexion';
            app.DropDown = uidropdown(app.ConexionPanel);
            app.DropDown.Items = {};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.Value = {};
            app.ConectarButton = uibutton(app.ConexionPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Enable = 'off';
            app.ConectarButton.Text = 'Conectar';
            app.ConectadoLampLabel = uilabel(app.ConexionPanel);
            app.ConectadoLampLabel.Text = 'Conectado';
            app.ConectadoLamp = uilamp(app.ConexionPanel);
            app.DropDown_2 = uidropdown(app.ConexionPanel);
            app.DropDown_2.Items = {'9600', '115200'};
            app.DropDown_2.Value = '9600';
            app.DesconectarButton = uibutton(app.ConexionPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Enable = 'off';
            app.DesconectarButton.Text = 'Desconectar';
            app.LeerButton = uibutton(app.ConexionPanel, 'push');
            app.LeerButton.ButtonPushedFcn = createCallbackFcn(app, @LeerButtonPushed, true);
            app.LeerButton.Text = 'Leer';
            app.EstadoLampLabel = uilabel(app.ConexionPanel);
            app.EstadoLampLabel.Text = 'Estado';
            app.EstadoLamp = uilamp(app.ConexionPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResumenTab = uitab(app.TabGroup);
            app.ResumenTab.Title = 'Resumen';
            app.SimbologiaEstadoPanel = uipanel(app.ResumenTab);
            app.SimbologiaEstadoPanel.Title = 'Simbologia Estado';
            app.ApagadoLampLabel = uilabel(app.SimbologiaEstadoPanel);
            app.ApagadoLampLabel.Text = 'Apagado';
            app.ApagadoLamp = uilamp(app.SimbologiaEstadoPanel);
            app.ProcesandoLampLabel = uilabel(app.SimbologiaEstadoPanel);
            app.ProcesandoLampLabel.Text = 'Procesando';
            app.ProcesandoLamp = uilamp(app.SimbologiaEstadoPanel);
            app.ProcesandodatosLampLabel = uilabel(app.SimbologiaEstadoPanel);
            app.ProcesandodatosLampLabel.Text = 'Procesando datos';
            app.ProcesandodatosLamp = uilamp(app.SimbologiaEstadoPanel);
            app.DatosmandadosLampLabel = uilabel(app.SimbologiaEstadoPanel);
            app.DatosmandadosLampLabel.Text = 'Datos mandados';
            app.DatosmandadosLamp = uilamp(app.SimbologiaEstadoPanel);
            app.ProgresogeneralPanel = uipanel(app.ResumenTab);
            app.ProgresogeneralPanel.Title = 'Progreso general';
            app.Gauge_2 = uigauge(app.ProgresogeneralPanel, 'circular');
            app.ResultadoPanel = uipanel(app.ResumenTab);
            app.ResultadoPanel.Title = 'Resultado';
            app.OxigenacionEditFieldLabel = uilabel(app.ResultadoPanel);
            app.OxigenacionEditFieldLabel.Text = 'Oxigenacion';
            app.OxigenacionEditField = uieditfield(app.ResultadoPanel, 'numeric');
            app.FueraderangoLampLabel = uilabel(app.ResultadoPanel);
            app.FueraderangoLampLabel.Text = 'Fuera de rango';
            app.FueraderangoLamp = uilamp(app.ResultadoPanel);
            app.SimbologiaRangosPanel = uipanel(app.ResumenTab);
            app.SimbologiaRangosPanel.Title = 'Simbologia Rangos';
            app.EstableLampLabel = uilabel(app.SimbologiaRangosPanel);
            app.EstableLampLabel.Text = 'Estable';
            app.EstableLamp = uilamp(app.SimbologiaRangosPanel);
            app.FueraderangoLamp_2Label = uilabel(app.SimbologiaRangosPanel);
            app.FueraderangoLamp_2Label.Text = 'Fuera de rango';
            app.FueraderangoLamp_2 = uilamp(app.SimbologiaRangosPanel);
            app.LeyendoLampLabel = uilabel(app.SimbologiaRangosPanel);
            app.LeyendoLampLabel.Text = 'Leyendo';
            app.LeyendoLamp = uilamp(app.SimbologiaRangosPanel);
            app.GraficasTab = uitab(app.TabGroup);
            app.GraficasTab.Title = 'Graficas';
            app.DatosPanel = uipanel(app.GraficasTab);
            app.DatosPanel.Title = 'Datos';
            app.UIAxes = uiaxes(app.DatosPanel);
            title(app.UIAxes, 'Oximetria')
            xlabel(app.UIAxes, 'Potencial (V)')
            ylabel(app.UIAxes, 'Oxigenacion')
            zlabel(app.UIAxes, 'Z')
            app.Tablas = uitab(app.TabGroup);
            app.Tablas.Title = 'Tablas';
            app.Panel = uipanel(app.Tablas);
            app.Panel.Title = 'Panel';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'# Dato'; 'Oxigenacion(%)'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxiCheck

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end